classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TwinkleTwinkleButton   matlab.ui.control.StateButton
        OscilatorsButtonGroup  matlab.ui.container.ButtonGroup
        TriangeButton          matlab.ui.control.ToggleButton
        SawthootButton         matlab.ui.control.ToggleButton
        SquareButton           matlab.ui.control.ToggleButton
        SineButton             matlab.ui.control.ToggleButton
        TimeSlider             matlab.ui.control.Slider
        TimeSliderLabel        matlab.ui.control.Label
        DButton_3              matlab.ui.control.Button
        CButton_3              matlab.ui.control.Button
        AButton_2              matlab.ui.control.Button
        GButton_2              matlab.ui.control.Button
        FButton_2              matlab.ui.control.Button
        DButton_2              matlab.ui.control.Button
        CButton_2              matlab.ui.control.Button
        AButton                matlab.ui.control.Button
        CButton                matlab.ui.control.Button
        GButton                matlab.ui.control.Button
        FButton                matlab.ui.control.Button
        DButton                matlab.ui.control.Button
        E5Button               matlab.ui.control.Button
        D5Button               matlab.ui.control.Button
        C3Button               matlab.ui.control.Button
        C5Button               matlab.ui.control.Button
        B3Button               matlab.ui.control.Button
        A3Button               matlab.ui.control.Button
        G3Button               matlab.ui.control.Button
        F3Button               matlab.ui.control.Button
        E3Button               matlab.ui.control.Button
        D3Button               matlab.ui.control.Button
        B4Button               matlab.ui.control.Button
        A4Button               matlab.ui.control.Button
        G4Button               matlab.ui.control.Button
        F4Button               matlab.ui.control.Button
        E4Button               matlab.ui.control.Button
        D4Button               matlab.ui.control.Button
        C4Button               matlab.ui.control.Button
        showHere               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)

        function playNote(app, note)
            
           
            tid = app.TimeSlider.Value;
            fs = 44100;
            t = linspace(0, tid, floor(tid * fs));

            switch note
                case "C4"
                    frekvens = 261.63;
                case "D4"
                    frekvens = 293.66;
                case "E4"
                    frekvens = 329.63;
                case "F4"
                    frekvens = 349.23;
                case "G4"
                    frekvens = 392.00;
                case "A4"
                    frekvens = 440.00;
                case "B4"
                    frekvens = 493.88;
                case "C3"
                    frekvens = 130.81;
                case "D3"
                    frekvens = 146.83;
                case "E3"
                    frekvens = 164.81;
                case "F3"
                    frekvens = 174.61;
                case "G3"
                    frekvens = 196.00;
                case "A3"
                    frekvens = 220.00;
                case "B3"
                    frekvens = 246.94;
                case "C5"
                    frekvens = 523.25;
                case "D5"
                    frekvens = 587.33;
                case "E5"
                    frekvens = 659.26;
                case "C#3"
                    frekvens = 138.591;
                case "D#3"
                    frekvens = 155.564;
                case "F#3"
                    frekvens = 184.997;
                case "G#3"
                    frekvens = 207.652;
                case "A#3"
                    frekvens = 233.082;
                case "C#4"
                    frekvens = 277.183;
                case "D#4"
                    frekvens = 311.127;
                case "F#4"
                    frekvens = 369.994;
                case "G#4"
                    frekvens = 415.305;
                case "A#4"
                    frekvens = 466.164;
                case "C#5"
                    frekvens = 554.365;
                case "D#5"
                    frekvens = 622.254;
                otherwise
                    disp('ERROR');
                    return;
            end
           
            % Get the selected radio button object
            selectedButton = app.OscilatorsButtonGroup.SelectedObject;
            %RadioButton
            % Check which radio button is selected and perform the corresponding action
            if selectedButton == app.SineButton
                ton = sin(2 * pi * frekvens * t);
            elseif selectedButton == app.SquareButton               
                ton = square(2 * pi * frekvens * t, 0.5);               
            elseif selectedButton == app.SawthootButton
                ton = sawtooth(2 * pi * frekvens * t, 0.7);
            elseif selectedButton == app.TriangeButton
                ton = sawtooth(2 * pi * frekvens * t, 0.5);
            end


            %Plot wave
            %T våglängd
            T=1/frekvens;
            plot(app.showHere, t, ton);  
            app.showHere.XLabel.String = 'Time (s) / 10';
            app.showHere.YLabel.String = 'Amplitude';
            app.showHere.XLim = [min(t) max(t)/10];
            app.showHere.YLim = [-1.5 1.5];
            app.showHere.Title.String = frekvens;


            sound(ton, fs);
            

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            playNote(app,"C4")
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            playNote(app,"D4")
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            playNote(app,"E4")
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            playNote(app,"F4")
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            playNote(app,"G4")
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            playNote(app,"A4")
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            playNote(app,"B4")
        end

        % Button pushed function: C3Button
        function C3ButtonPushed(app, event)
            playNote(app,"C3")
        end

        % Button pushed function: D3Button
        function D3ButtonPushed(app, event)
            playNote(app,"D3")
        end

        % Button pushed function: E3Button
        function E3ButtonPushed(app, event)
            playNote(app,"E3")
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            playNote(app,"F3")
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            playNote(app,"G3")
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            playNote(app,"A3")
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            playNote(app,"B3")
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            playNote(app,"C5")
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            playNote(app,"D5")
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            playNote(app,"E5")
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            playNote(app,"C#3")
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            playNote(app,"D#3")
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            playNote(app,"F#3")
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            playNote(app,"G#3")
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            playNote(app,"A#3")
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            playNote(app,"C#4")
        end

        % Button pushed function: DButton_2
        function DButton_2Pushed(app, event)
            playNote(app,"D#4")
        end

        % Button pushed function: FButton_2
        function FButton_2Pushed(app, event)
            playNote(app,"F#4")
        end

        % Button pushed function: GButton_2
        function GButton_2Pushed(app, event)
            playNote(app,"G#4")
        end

        % Button pushed function: AButton_2
        function AButton_2Pushed(app, event)
            playNote(app,"A#4")
        end

        % Button pushed function: CButton_3
        function CButton_3Pushed(app, event)
            playNote(app,"C#5")
        end

        % Button pushed function: DButton_3
        function DButton_3Pushed(app, event)
            playNote(app,"D#5")
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            %value = app.TimeSlider.Value;


        end

        % Value changed function: TwinkleTwinkleButton
        function playTwinkle(app, event)
            % Define the frequencies of the notes
C4 = 261.63;
D4 = 293.66;
E4 = 329.63;
F4 = 349.23;
G4 = 392.00;
A4 = 440.00;
PAUSE = 0;

% Define the song as an array of frequencies
song = [C4, C4, G4, G4, A4, A4, G4, PAUSE, ...
        F4, F4, E4, E4, D4, D4, C4, PAUSE, ...
        G4, G4, F4, F4, E4, E4, D4, PAUSE, ...
        G4, G4, F4, F4, E4, E4, D4, PAUSE, ...
        C4, C4, G4, G4, A4, A4, G4, PAUSE, ...
        F4, F4, E4, E4, D4, D4, C4, PAUSE];

% Define the sample rate and the duration of each note
fs = 44100; % Sample rate in Hz
duration = 0.5; % Duration of each note in seconds

% Play the song
for i = 1:length(song)
    freq = song(i);
    if freq == PAUSE
        pause(duration); % If it is a pause, just wait for the duration of a note
    else
        t = 0:1/fs:duration; % Time vector for the note
        note = sin(2 * pi * freq * t); % Generate the note
        sound(note, fs); % Play the note
        pause(duration); % Wait for the note to finish
    end
end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.showHere = uiaxes(app.UIFigure);
            title(app.showHere, 'Title')
            xlabel(app.showHere, 'X')
            ylabel(app.showHere, 'Y')
            zlabel(app.showHere, 'Z')
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.D3Button = uibutton(app.UIFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3Button.Text = 'D3';
            app.E3Button = uibutton(app.UIFigure, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3ButtonPushed, true);
            app.E3Button.Text = 'E3';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.C3Button = uibutton(app.UIFigure, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3Button.Text = 'C3';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D#';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F#';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G#';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C#';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A#';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C#';
            app.DButton_2 = uibutton(app.UIFigure, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @DButton_2Pushed, true);
            app.DButton_2.Text = 'D#';
            app.FButton_2 = uibutton(app.UIFigure, 'push');
            app.FButton_2.ButtonPushedFcn = createCallbackFcn(app, @FButton_2Pushed, true);
            app.FButton_2.Text = 'F#';
            app.GButton_2 = uibutton(app.UIFigure, 'push');
            app.GButton_2.ButtonPushedFcn = createCallbackFcn(app, @GButton_2Pushed, true);
            app.GButton_2.Text = 'G#';
            app.AButton_2 = uibutton(app.UIFigure, 'push');
            app.AButton_2.ButtonPushedFcn = createCallbackFcn(app, @AButton_2Pushed, true);
            app.AButton_2.Text = 'A#';
            app.CButton_3 = uibutton(app.UIFigure, 'push');
            app.CButton_3.ButtonPushedFcn = createCallbackFcn(app, @CButton_3Pushed, true);
            app.CButton_3.Text = 'C#';
            app.DButton_3 = uibutton(app.UIFigure, 'push');
            app.DButton_3.ButtonPushedFcn = createCallbackFcn(app, @DButton_3Pushed, true);
            app.DButton_3.Text = 'D#';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0.1 1];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 0.5;
            app.OscilatorsButtonGroup = uibuttongroup(app.UIFigure);
            app.OscilatorsButtonGroup.Title = 'Oscilators';
            app.SineButton = uitogglebutton(app.OscilatorsButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.SquareButton = uitogglebutton(app.OscilatorsButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SawthootButton = uitogglebutton(app.OscilatorsButtonGroup);
            app.SawthootButton.Text = 'Sawthoot';
            app.TriangeButton = uitogglebutton(app.OscilatorsButtonGroup);
            app.TriangeButton.Text = 'Triange';
            app.TwinkleTwinkleButton = uibutton(app.UIFigure, 'state');
            app.TwinkleTwinkleButton.ValueChangedFcn = createCallbackFcn(app, @playTwinkle, true);
            app.TwinkleTwinkleButton.Text = 'TwinkleTwinkle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end